classdef VideoPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Button                 matlab.ui.control.Button
        DropDown               matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        VideoFileBrowseButton  matlab.ui.control.Button
        VideoFileEditField     matlab.ui.control.EditField
        VideoFileLabel         matlab.ui.control.Label
        TimeStatusLabel        matlab.ui.control.Label
        RepeatButton           matlab.ui.control.StateButton
        StopButton             matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Graphics objects
        % CursorLine

        % Data objects
        pVideoFileReader
        % pVideoPlayer
        % pVideoLevelMeter
        tEnd

        % Flags and other runtime data
        VideoFileName (1,1) string
        VideoFileReadPointer (1,1) double = 0
        PlaybackState (1,1) string {mustBeMember(PlaybackState, ...
            ["nodata", "ready", "playing", "paused"])} = "nodata"
        PauseRequested (1,1) logical = false
        StopRequested (1,1) logical = false
        IsRepeatPlayback (1,1) logical = false
    end

    methods (Access = private)
        function setupAxes(app)
            % Add axes toolbar with zooming and panning buttons. Disable
            % axes interactivity for better performance.
            ax = app.UIAxes;
            axis(ax,"off")
            % axtoolbar(ax, {'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(ax);
        end

        function loadVideoFile(app)
            % Load audio data and plot

            % Read audio data from the file
            % y = [];
            % t = duration(0,0,[]);
            isValidVideoFIle = false;
            try
                % [y,fs] = audioread(app.VideoFileName);
                % t = seconds(0:1/fs:(size(y,1)-1)/fs);
                app.pVideoFileReader = VideoReader(app.VideoFileName);
                isValidVideoFIle = true;
            catch ME
                uialert(app.UIFigure,ME.message,'Invalid File');
            end

            % Clear the axes and plot the audio data
            ax = app.UIAxes;
            cla(ax);

            % Set up data objects and update app components
            if isValidVideoFIle
                frame = readFrame(app.pVideoFileReader);
                image(ax,frame)

                app.tEnd =  seconds(app.pVideoFileReader.Duration);
                app.tEnd.Format = 'hh:mm:ss';
                app.Slider.Limits = [0,app.pVideoFileReader.Duration+1];
                setPlaybackPosition(app,[]);
                % Update the playback toolbar buttons
                setPlaybackState(app,'ready');
            else
                setPlaybackState(app,'nodata');
            end
        end

        function setPlaybackState(app, playbackState)
            % Update the app components when the playback state changes

            % Disable loading an audio file during playback
            app.VideoFileEditField.Enable = ~ismember(playbackState,{'playing','paused'});
            app.VideoFileBrowseButton.Enable = ~ismember(playbackState,{'playing','paused'});

            % Set playback buttons enableness
            app.PlayButton.Enable = ismember(playbackState,{'ready','playing','paused'});
            app.StopButton.Enable = ismember(playbackState,{'playing','paused'});
            app.RepeatButton.Enable = ismember(playbackState,{'ready'});

            % Set play button mode
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if strcmp(playbackState,'playing')
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_pause_24.png');
                app.PlayButton.Tooltip = 'Pause';
            elseif strcmp(playbackState,'paused')
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_play_24.png');
                app.PlayButton.Tooltip = 'Continue';
            else
                app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets/media_control_play_24.png');
                app.PlayButton.Tooltip = 'Play';
            end

            % Save the playback state and flush the graphics queue
            app.PlaybackState = playbackState;
            drawnow
        end

        function setPlaybackPosition(app,xLoc)
            % Set the playback position by updating the cursor location and
            % the time status
            if isempty(xLoc)
                % cursorLine.Visible = 'off';
                app.Slider.Value = 0;
                tStr = string(duration(0,0,0,'Format','hh:mm:ss'));
            else
                % set(cursorLine, 'XData', [xLoc,xLoc], 'Visible', 'on');
                app.Slider.Value = seconds(xLoc);
                xLoc.Format = 'hh:mm:ss';
                tStr = string(xLoc);
            end
            
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',tStr,string(app.tEnd));
        end

        function play(app)
            % player = app.pVideoPlayer;
            reader = app.pVideoFileReader;
            ax = app.UIAxes;
            % levelMeter = app.pVideoLevelMeter;
            % uimeter = app.Meter;

            % If this is new playback, reset the read pointer
            % if app.PlaybackState == "ready"
            %     app.VideoFileReadPointer = 0;
            % end
            % fs = reader.SampleRate;
            % totalSamples = reader.TotalSamples;
            % currPointer = app.VideoFileReadPointer;

            % Start playback
            setPlaybackState(app, 'playing');
            while hasFrame(reader) &&  ~(app.StopRequested || app.PauseRequested)
                % Read video data and play
                frame = readFrame(reader);
                image(ax,frame);

                setPlaybackPosition(app,seconds(reader.CurrentTime));

                % Call drawnow to update graphics
                % drawnow %limitrate
                pause(1/reader.FrameRate);
            end

            if app.PauseRequested
                setPlaybackState(app, 'paused');
                app.PauseRequested = false;
            else
                stop(app);
                app.StopRequested = false;
            end
        end

        function stop(app)
            app.pVideoFileReader.CurrentTime=0;
            frame = readFrame(app.pVideoFileReader);
            image(app.UIAxes,frame);

            setPlaybackPosition(app,[]);
            setPlaybackState(app, 'ready');

            if app.RepeatButton.Value
                play(app)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create model objects
            % frameLen = 512;
            % app.pVideoFileReader = dsp.AudioFileReader(SamplesPerFrame=frameLen);
            % app.pVideoPlayer = audioDeviceWriter;
            % app.pVideoLevelMeter = audioLevelMeter(WindowLength=frameLen);
            % Initialize the axes
            setupAxes(app);
            % Load a default audio file
            app.VideoFileName = './Assets/360panodemo.mp4';
            app.VideoFileEditField.Value = app.VideoFileName;
            loadVideoFile(app);
        end

        % Button pushed function: VideoFileBrowseButton
        function VideoFileBrowseButtonPushed(app, event)
            if (exist(app.VideoFileName,'file'))
                currFile = which(app.VideoFileName);
            else
                currFile = app.VideoFileName;
            end

            % Browse for video files using uigetfile
            dialogTitle = "Select Input Video File";
            % videoFilesTitle = "(*.mp4,*.avi, ...)";
            % allFilesTitle = "All Files (*.*)";
            % audioFileExts = dsp.AudioFileReader.getSupportedFileExtensions();
            % audioFileFormats = join(strcat('*', audioFileExts(:)),';');
            filterSpec = {'*.mp4';'*.avi'};
            [filename, pathname] = uigetfile(filterSpec,dialogTitle,currFile);

            if filename
                % Change the property and update the edit field
                app.VideoFileName = fullfile(pathname,filename);
                app.VideoFileEditField.Value = app.VideoFileName;
                loadVideoFile(app);
            end
        end

        % Value changed function: VideoFileEditField
        function VideoFileEditFieldValueChanged(app, event)
            app.VideoFileName = app.VideoFileEditField.Value;
            loadVideoFile(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            try
                if app.PlaybackState == "playing"
                    app.PauseRequested = true;
                else
                    % A new start or continue previously paused
                    play(app);
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error during playback');
                stop(app);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.PlaybackState == "playing"
                app.StopRequested = true;
            else
                stop(app);
            end
        end

        % Value changed function: RepeatButton
        function RepeatButtonValueChanged(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.RepeatButton.Value
                % app.pVideoFileReader.PlayCount = Inf;
                app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets','media_control_repeat_on_24.png');
            else
                % app.pVideoFileReader.PlayCount = 1;
                app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets','media_control_repeat_off_24.png');
            end
        end

        % Value changing function: Slider
        function progressChangingSliderFcn(app, event)
            
            try
                if app.PlaybackState == "playing"
                    app.PauseRequested = true;
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error during progressChangingSliderFcn');
                stop(app);
            end


            changingValue = event.Value;

            app.pVideoFileReader.CurrentTime = min(changingValue,app.pVideoFileReader.Duration);
            if hasFrame(app.pVideoFileReader)
                frame = readFrame(app.pVideoFileReader);
                image(app.UIAxes,frame);
            end

            tStr = seconds(changingValue);
            tStr.Format = "hh:mm:ss";
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',string(tStr),string(app.tEnd));

           drawnow;% limitrate
        end

        % Value changed function: Slider
        function progressChangedFcn(app, event)
            % value = app.Slider.Value;
            value = event.Value;
            tStr = seconds(value);
            tStr.Format = "hh:mm:ss";
            app.TimeStatusLabel.Text = sprintf('T = %s/%s',string(tStr),string(app.tEnd));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Audio Player with Meter';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_play_24.png');
            app.PlayButton.Tooltip = {'Play'};
            app.PlayButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_stop_24.png');
            app.StopButton.Tooltip = {'Stop playing'};
            app.StopButton.Text = '';
            app.RepeatButton = uibutton(app.UIFigure, 'state');
            app.RepeatButton.ValueChangedFcn = createCallbackFcn(app, @RepeatButtonValueChanged, true);
            app.RepeatButton.Tooltip = {'Play in a loop'};
            app.RepeatButton.Icon = fullfile(pathToMLAPP, 'Assets', 'media_control_repeat_off_24.png');
            app.RepeatButton.Text = '';
            app.TimeStatusLabel = uilabel(app.UIFigure);
            app.TimeStatusLabel.Text = 'T = 00:00:00/ 00:00:00';
            app.VideoFileLabel = uilabel(app.UIFigure);
            app.VideoFileLabel.Text = 'Video File';
            app.VideoFileEditField = uieditfield(app.UIFigure, 'text');
            app.VideoFileEditField.ValueChangedFcn = createCallbackFcn(app, @VideoFileEditFieldValueChanged, true);
            app.VideoFileEditField.Placeholder = 'Enter video file path';
            app.VideoFileBrowseButton = uibutton(app.UIFigure, 'push');
            app.VideoFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @VideoFileBrowseButtonPushed, true);
            app.VideoFileBrowseButton.Text = 'Browse';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @progressChangedFcn, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @progressChangingSliderFcn, true);
            app.Slider.MinorTicks = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '视图';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'透视图', '小行星', '水晶球'};
            app.DropDown.Value = '透视图';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '导出视频';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VideoPlayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end